classdef VentanaPrincipal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout_Principal  matlab.ui.container.GridLayout
        Container_Principal   matlab.ui.container.Panel
        TabGroup_Principal    matlab.ui.container.TabGroup
        Tab_Inicio            matlab.ui.container.Tab
        InicioLabel           matlab.ui.control.Label
        Tab_Teoria            matlab.ui.container.Tab
        TeoriaLabel           matlab.ui.control.Label
        Tab_Evaluacion        matlab.ui.container.Tab
        EvaluacionLabel       matlab.ui.control.Label
        Tab_Calculadora       matlab.ui.container.Tab
        CalculadoraLabel      matlab.ui.control.Label
        Label_Titulo          matlab.ui.control.Label
        Label_Tema            matlab.ui.control.Label
        Panel_Principal       matlab.ui.container.Panel
        Button_Teoria         matlab.ui.control.Button
        Button_Evaluacion     matlab.ui.control.Button
        Button_Inicio         matlab.ui.control.Button
        Button_Calculadora    matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property_Color01=[0.0588 0.1882 0.3412] % Azul Oscuro - Letras principal, Fondo alternativo
        Property_Color02=[0 0.3451 0.4784] % Azul Claro
        Property_Color03=[0 0.5333 0.5686] % Verde Agua
        Property_Color04=[0.9255 0.9255 0.9255] % Blanco Frio - Fondo principal, Letras salternativas
    end
    
    methods (Access = private)
        % Funcion que colorea los botones diferentes al que genera el
        % evento
        function Button_BarraNavegacion_ModificarColores(app,index)
            if index~=1
                app.Button_Inicio.BackgroundColor = app.Property_Color03;
                app.Button_Inicio.FontColor = app.Property_Color04;
            end
            if index~=2
                app.Button_Teoria.BackgroundColor = app.Property_Color03;
                app.Button_Teoria.FontColor = app.Property_Color04;
            end
            if index~=3
                app.Button_Evaluacion.BackgroundColor = app.Property_Color03;
                app.Button_Evaluacion.FontColor = app.Property_Color04;
            end
            if index~=4
                app.Button_Calculadora.BackgroundColor = app.Property_Color03;
                app.Button_Calculadora.FontColor = app.Property_Color04;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button_Inicio
        function ButtonPushed_Inicio(app, event)
            Button_BarraNavegacion_ModificarColores(app,1);
            app.Button_Inicio.BackgroundColor = app.Property_Color04;
            app.Button_Inicio.FontColor = app.Property_Color03;
            app.TabGroup_Principal.SelectedTab=app.Tab_Inicio;  %Seleccionar pestaña
        end

        % Button pushed function: Button_Teoria
        function ButtonPushed_Teoria(app, event)
            Button_BarraNavegacion_ModificarColores(app,2);
            app.Button_Teoria.BackgroundColor = app.Property_Color04;
            app.Button_Teoria.FontColor = app.Property_Color03;
            app.TabGroup_Principal.SelectedTab=app.Tab_Teoria;  %Seleccionar pestaña
        end

        % Button pushed function: Button_Evaluacion
        function ButtonPushed_Evaluacion(app, event)
            Button_BarraNavegacion_ModificarColores(app,3);
            app.Button_Evaluacion.BackgroundColor = app.Property_Color04;
            app.Button_Evaluacion.FontColor = app.Property_Color03;
            app.TabGroup_Principal.SelectedTab=app.Tab_Evaluacion;  %Seleccionar pestaña
        end

        % Button pushed function: Button_Calculadora
        function ButtonPushed_Calculadora(app, event)
            Button_BarraNavegacion_ModificarColores(app,4);
            app.Button_Calculadora.BackgroundColor = app.Property_Color04;
            app.Button_Calculadora.FontColor = app.Property_Color03;
            app.TabGroup_Principal.SelectedTab=app.Tab_Calculadora;  %Seleccionar pestaña
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout_Principal = uigridlayout(app.UIFigure);
            app.Container_Principal = uipanel(app.GridLayout_Principal);
            app.TabGroup_Principal = uitabgroup(app.Container_Principal);
            app.Tab_Inicio = uitab(app.TabGroup_Principal);
            app.Tab_Inicio.Title = 'Inicio';
            app.InicioLabel = uilabel(app.Tab_Inicio);
            app.InicioLabel.Text = 'Inicio';
            app.Tab_Teoria = uitab(app.TabGroup_Principal);
            app.Tab_Teoria.Title = 'Teoria';
            app.TeoriaLabel = uilabel(app.Tab_Teoria);
            app.TeoriaLabel.Text = 'Teoria';
            app.Tab_Evaluacion = uitab(app.TabGroup_Principal);
            app.Tab_Evaluacion.Title = 'Evaluacion';
            app.EvaluacionLabel = uilabel(app.Tab_Evaluacion);
            app.EvaluacionLabel.Text = 'Evaluacion';
            app.Tab_Calculadora = uitab(app.TabGroup_Principal);
            app.Tab_Calculadora.Title = 'Calculadora';
            app.CalculadoraLabel = uilabel(app.Tab_Calculadora);
            app.CalculadoraLabel.Text = 'Calculadora';
            app.Label_Titulo = uilabel(app.Container_Principal);
            app.Label_Titulo.Text = 'SOFTWARE DE ENSEÑANZA';
            app.Label_Tema = uilabel(app.Container_Principal);
            app.Label_Tema.Text = 'Sistema de ecuaciones';
            app.Panel_Principal = uipanel(app.Container_Principal);
            app.Button_Teoria = uibutton(app.Panel_Principal, 'push');
            app.Button_Teoria.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Teoria, true);
            app.Button_Teoria.Text = 'Teoria';
            app.Button_Evaluacion = uibutton(app.Panel_Principal, 'push');
            app.Button_Evaluacion.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Evaluacion, true);
            app.Button_Evaluacion.Text = 'Evaluacion';
            app.Button_Inicio = uibutton(app.Panel_Principal, 'push');
            app.Button_Inicio.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Inicio, true);
            app.Button_Inicio.Text = 'Inicio';
            app.Button_Calculadora = uibutton(app.Panel_Principal, 'push');
            app.Button_Calculadora.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Calculadora, true);
            app.Button_Calculadora.Text = 'Calculadora';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VentanaPrincipal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end